classdef dataStackedPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DataloadedCheckBox       matlab.ui.control.CheckBox
        LoadloggerdataNoteOnlycsvfilesLabel  matlab.ui.control.Label
        LoadDataButton           matlab.ui.control.Button
        SettingsLabel            matlab.ui.control.Label
        ChangeplotteddataButton  matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        VarList % Description
        Data
    end
    
    methods (Access = private)
        
        function plotVarList(app, VarList)
            stackedplot(app.UIFigure, app.Data, VarList);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VarList={};
            app.DataloadedCheckBox.Value = false;
        end

        % Button pushed function: ChangeplotteddataButton
        function ChangeplotteddataButtonPushed(app, event)
            temp = ChangePlottedValues(app.Data.Properties.VariableNames);
            while temp.RDY == false
                pause(0.5);
            end
            returnedNodes = temp.treeNodes;
            NewPlotVarSize = size(returnedNodes,1);
            for i = 1:NewPlotVarSize
                app.VarList{i} = string(returnedNodes(i).Text);
            end
            temp.delete;

            plotVarList(app, app.VarList);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fname,pname] = uigetfile('.csv');
            app.Data = readtimetable(strcat(pname, fname));
            app.DataloadedCheckBox.Value = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChangeplotteddataButton = uibutton(app.UIFigure, 'push');
            app.ChangeplotteddataButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeplotteddataButtonPushed, true);
            app.ChangeplotteddataButton.Text = 'Change plotted data';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadloggerdataNoteOnlycsvfilesLabel = uilabel(app.UIFigure);
            app.LoadloggerdataNoteOnlycsvfilesLabel.Text = {'Load logger data'; 'Note: Only .csv files'};
            app.DataloadedCheckBox = uicheckbox(app.UIFigure);
            app.DataloadedCheckBox.Enable = 'off';
            app.DataloadedCheckBox.Text = 'Data loaded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataStackedPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end